classdef OrganoidAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        menuFile                 matlab.ui.container.Menu
        OpenImageFile            matlab.ui.container.Menu
        SetImagePathMenu         matlab.ui.container.Menu
        Assign2Workspace         matlab.ui.container.Menu
        AssignImg2Workspace      matlab.ui.container.Menu
        LoadfromWorkspaceMenu    matlab.ui.container.Menu
        VolumeMenu               matlab.ui.container.Menu
        AdjustContrastMenu       matlab.ui.container.Menu
        OverlayChannelsMenu      matlab.ui.container.Menu
        RepositionPixelInfoMenu  matlab.ui.container.Menu
        FilterVolumeMenu         matlab.ui.container.Menu
        SegmentVolumeMenu        matlab.ui.container.Menu
        ShowOriginalVolume       matlab.ui.container.Menu
        SegmentMenu              matlab.ui.container.Menu
        ShowMasksMenu            matlab.ui.container.Menu
        BlendMaskswithVolsMenu   matlab.ui.container.Menu
        ShowLabelMenu            matlab.ui.container.Menu
        ShowMaskOverlayMenu      matlab.ui.container.Menu
        SeparateLabelClusters    matlab.ui.container.Menu
        CalculateDataMenu        matlab.ui.container.Menu
        ColormapMenu             matlab.ui.container.Menu
        grayMenu                 matlab.ui.container.Menu
        parulaMenu               matlab.ui.container.Menu
        turboMenu                matlab.ui.container.Menu
        hotMenu                  matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        PanelLabelControl        matlab.ui.container.Panel
        UNDOButton               matlab.ui.control.Button
        MergeWith                matlab.ui.control.StateButton
        LabelSpinner             matlab.ui.control.Spinner
        LabelSpinnerLabel        matlab.ui.control.Label
        MergeButton              matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        ThresholdSettingsLabel   matlab.ui.control.Label
        Ch1SpinnerLabel          matlab.ui.control.Label
        Ch1Spinner               matlab.ui.control.Spinner
        Ch2Spinner               matlab.ui.control.Spinner
        Ch2SpinnerLabel          matlab.ui.control.Label
        hAx2                     matlab.ui.control.UIAxes
        hAx1                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        imagePath % Path to current image volume
        volSize % dimension of loaded volume
        volTotalSlice % total number of slices
        volCurrentSlcIdx % current index in volume
        hImg1 % handles to displayed slices
        hImg2
        hPixInfo % handle to pixel info tool
        hVolDisp3D % handle to volDisp3D app
        hDataDlg % handle to the Data Dialog
        pLABEL % previous Label
    end

    properties (Access = public)
        VOL % load dataset
        VOLf % filtered volume
        MASK % MASK of dataset
        LABEL % label of large organoids
        volMeta % metadata
    end

    methods (Access = private)

        % LOAD VOLUME
        function LoadVolume(app)

            if isempty(app.imagePath)
                readVOL(app)
            else
                readVOL(app,app.imagePath);
            end

            if ~isempty(app.VOL)
                app.VOL = single(app.VOL);
                app.VOL(:,:,1,:) = mat2gray(app.VOL(:,:,1,:)); % normalize volume
                app.VOL(:,:,2,:) = mat2gray(app.VOL(:,:,2,:)); %    by channel

                app.MASK = [];
                app.LABEL = [];
                app.VOLf = [];

                app.imagePath = app.volMeta.fullPath;
                figure(app.UIFigure) % bring app to the front
                title(app.hAx1,sprintf('%s: %d | Ch1',replace(app.volMeta.fileName,'_',' '), app.volMeta.SeriesSelected));

                SliceInit(app)
            end
        end

        % SLICE ROLLOVER - makes sure current index stays within range
        function idx = SliceIdxRollover(app,idx)
            % Roll over control
            if idx > app.volTotalSlice
                % If index exceeds number of slices in stack, go to first slice
                idx = 1;
            elseif idx < 1
                % if index drops below 1, go to the last slice
                idx = app.volTotalSlice;
            end
        end

        % SLICE UPDATE - updates slice display on mouse scroll
        function SliceUpdate(app)

            if app.ShowOriginalVolume.Checked || isempty(app.VOLf)
                vol = app.VOL;
            else
                vol = app.VOLf;
            end

            % get slice
            % img = squeeze(app.VOL(:,:,:,app.volCurrentSlcIdx));
            img = squeeze(vol(:,:,:,app.volCurrentSlcIdx));
            if app.AdjustContrastMenu.Checked % adjust image contrast as needed
                slice1 = imadjust(img(:,:,1));
                slice2 = imadjust(img(:,:,2));
            else
                slice1 = img(:,:,1);
                slice2 = img(:,:,2);
            end

            if ~isempty(app.MASK)
                % get slice and mask to display
                if app.ShowLabelMenu.Checked
                    L = app.LABEL(:,:,app.volCurrentSlcIdx); % get label for Ch1
                    % slice1 = label2rgb(L,'jet');
                    slice1 = L;
                    slice2 = squeeze(app.MASK(:,:,2,app.volCurrentSlcIdx)); % get mask for Ch2
                elseif app.ShowMasksMenu.Checked
                    mask = squeeze(app.MASK(:,:,:,app.volCurrentSlcIdx)); % get mask

                    if app.BlendMaskswithVolsMenu.Checked % blend mask
                        slice1 = imfuse(img(:,:,1), mask(:,:,1));
                        slice2 = imfuse(img(:,:,2), mask(:,:,2));
                    else
                        slice1 = single(mask(:,:,1));
                        slice2 = single(mask(:,:,2));
                    end
                end
            end

            set(app.hImg1,CData = slice1);
            set(app.hImg2,CData = slice2);

            set(app.UIFigure,'Name',sprintf('OrganoidAnalyzer: %d | %d',app.volCurrentSlcIdx,app.volTotalSlice))

        end

        % APPLY FILTER TO VOLUME
        function FilterVolume(app)
            app.VOLf = app.VOL; % reset filtered volume

            d = uiprogressdlg(app.UIFigure,'Title','Applying Filters',...
                Message='Please wait');
            for n=1:2

                d.Message = sprintf('Extracting Channel %d',n); % update progress bar
                d.Value = 0.125+(1-1/n); 

                vol = squeeze(app.VOL(:,:,n,:)); % extract channel

                d.Message = sprintf('Ch%d: Applying Median Filter',n);
                d.Value = 0.25+(1-1/n);% update progress bar

                volF = medfilt3(vol); % apply a median filter

                d.Message = sprintf('Ch%d: Applying Guassian Filter',n);

                volF = imgaussfilt3(volF,1,"FilterSize",[5 5 5]);

                d.Message = sprintf('Ch%d: Applying Erode with Reconstruction',n);  % update progress bar
                d.Value = 0.375+(1-1/n);

                % Erode and Reconstruct to enhance contrast between cells
                se = strel("sphere",3); % create a sphere structuring element with a radius of 3
                volEr = imerode(volF,se);
                volF = imreconstruct(volEr,volF);

                app.VOLf(:,:,n,:) = volF; % updating volume

            end

            d.Message = 'Finishing.';
            close(d)

            % ACTIVATE MENU ITEMS
            app.ShowOriginalVolume.Enable="on";
            app.ShowOriginalVolume.Checked="off";
            SliceUpdate(app)
        end

        % SEGMENT CHANNELS
        function SegmentVolume(app)
            
            if isempty(app.VOLf)
                uialert(app.UIFigure,"Filter the Volume First","Segmentation Error","Icon","error")
                return
            end

            app.MASK = false(size(app.VOLf));

            segmentProtocol = 'b';
            segmentFactor = [app.Ch1Spinner.Value, app.Ch2Spinner.Value];

            d = uiprogressdlg(app.UIFigure,'Title','Segmenting Organoids',...
                Message='Please wait...');
            for n=1:2
                d.Message = sprintf('Channel %d',n);
                vol = squeeze(app.VOLf(:,:,n,:));

                switch segmentProtocol
                    case 'a'
                        T = mean(vol,"all") + std(vol,[],"all");
                    case 'b'
                        T = graythresh(vol) * segmentFactor(n);
                end
                app.MASK(:,:,n,:) = imbinarize(vol,T);

                d.Value = (n/2)-0.1;
            end
            mask = squeeze(or(app.MASK(:,:,1,:), app.MASK(:,:,2,:))); % merge channels
            se = strel("sphere",3);
            mask = imclose(mask,se);

            mask = imfill(mask,'holes');
            mask = bwareaopen(mask,100);

            app.LABEL = single(bwlabeln(mask)); % label map of organoids

            % ACTIVATE MENU ITEMS
            app.SegmentMenu.Enable = "on";
            app.ShowLabelMenu.Checked = "on";
            app.PanelLabelControl.Enable = "on";
            labelRange = double([0 max(app.LABEL(:))]);
            app.LabelSpinner.Limits=labelRange;

            % update slice and colormap
            SliceUpdate(app)
            colormap(app.hAx1,"turbo")
            app.hAx1.CLim = double([0 max(app.LABEL(:))]);

            close(d)

        end

        % % UPDATE CHECKED MENUS
        % function UpdateCheckedMenus(app)
        %
        %     if app.ShowLabelMenu.Checked
        %         app.ShowMasksMenu.Checked = "off";
        %         app.BlendMaskswithVolsMenu.Checked = "off";
        %     end
        %
        %     if app.BlendMaskswithVolsMenu.Checked
        %         app.ShowMasksMenu.Checked="on";
        %     end
        % end

        function SegmentVolumeBySlice(app)
            app.MASK = false(size(app.VOL));

            d = uiprogressdlg(app.UIFigure,'Title','Segmenting Organoids',...
                Message='Please wait...');
            for n=1:2
                d.Message = sprintf('Channel %d',n);
                T = mean(app.VOL(:,:,n,:),"all") + std(app.VOL(:,:,n,:),[],"all");
                vol = squeeze(app.VOL(:,:,n,:));

                app.MASK(:,:,n,:) = imbinarize(vol,T);
                d.Value = n/2;
            end
            app.LABEL = single(squeeze(bwlabeln(app.MASK(:,:,1,:)))); % label map of organoids

            app.SegmentMenu.Enable = "on";
            app.ShowLabelMenu.Checked = "on";


            close(d)
            SliceUpdate(app)
        end

        % READ In VOLUMES
        function readVOL(app,varargin)

            if exist("bfmatlab","dir")
                status = bfCheckJavaPath(1);
                assert(status, ['Missing Bio-Formats library. Either add bioformats_package.jar '...
                    'to the static Java path or add it to the Matlab path.']);
            else
                disp("bioformats toolbox not found. Be sure to add 'bfmatlab' to the file path")
                return
            end

            % Prompt for a file if no input
            if isempty(varargin) || ~exist(varargin{1},'file')
                [file, path] = uigetfile(bfGetFileExtensions, 'Choose a file to open');
                fullPath = fullfile(path, file);
                if ~file
                    return;
                end
            else
                fullPath = varargin{1};
            end

            figure(app.UIFigure)
            d = uiprogressdlg(app.UIFigure,'Title','Reading Image',...
                Message='Please wait');

            % set up logger - part of bioformats
            bfInitLogging();

            % initilize stack reader
            % r = bfGetReader(char(fullPath));
           
            % Memoizer - for speed in reading
            r = bfGetReader();
            r = loci.formats.Memoizer(r); % seems to crash on PCs
            r.setId(char(fullPath));

            % load OME metadata
            omeMeta = r.getMetadataStore(); % different from imfinfo

            % set series (should only be one series)
            num_series = r.getSeriesCount;

            if num_series > 1
                s = listdlg('PromptString', 'Select series number to load','ListString',string(0:num_series-1));
                series_jidx = s-1;
                figure(app.UIFigure)
            else
                series_jidx = 0;
            end

            bit_string = char(omeMeta.getPixelsType(series_jidx)); % get bit depth
            r.setSeries(series_jidx);

            % get stack info
            ChannelCount = r.getSizeC;
            PlaneCount = r.getSizeZ;
            TimeCount= r.getSizeT;

            totalPlanes = ChannelCount*PlaneCount;
            app.VOL = zeros(r.getSizeY, r.getSizeX, ChannelCount, PlaneCount, TimeCount,bit_string);

            for t = 0:TimeCount-1
                for z=0:PlaneCount-1
                    for c = 0:ChannelCount-1
                        PlaneIndex = r.getIndex(z, c, t);
                        app.VOL(:,:,c+1,z+1,t+1) = bfGetPlane(r,PlaneIndex+1);
                    end
                    d.Value = z*2/totalPlanes;
                end
            end

            app.imagePath = fullPath; % used in Load Volume function, default is empty
            app.volMeta.fullPath = fullPath;
            [~, fileName] = fileparts(fullPath); % image page
            app.volMeta.fileName = fileName;
            app.volMeta.SeriesSelected = series_jidx;
            close(d)

        end
    end

    methods (Access = public)

        % INIITIALIZE SLICE DISPLAY
        function SliceInit(app)
            % Channel 1 displayed in Ax1, Channel2 displayed in Ax2
            % referred to this page: https://www.mathworks.com/matlabcentral/answers/360670-imshow-in-app-designer-image-size-doesn-t-fit

            app.volSize = size(app.VOL);
            app.volTotalSlice = size(app.VOL,ndims(app.VOL)); % get number of slices
            app.volCurrentSlcIdx = round(app.volTotalSlice/2); % update for color image

            slice = app.VOL(:,:,:,app.volCurrentSlcIdx); % two channel slice

            if app.AdjustContrastMenu.Checked% adjust image contrast as needed
                slice(:,:,1,:) = imadjust(slice(:,:,1,:));
                slice(:,:,2,:) = imadjust(slice(:,:,2,:));
            end

            % display slices in hAx1 and hAx2, hImg1, hImg2
            for n=1:2
                app.(sprintf('hImg%d',n)) = imshow(slice(:,:,n), Parent = app.(sprintf('hAx%d',n))); % display slice

                % expand slice to full axes
                app.(sprintf('hAx%d',n)).XLim = [0 app.(sprintf('hImg%d',n)).XData(2)]; % manage XLim (for proper slice display)
                app.(sprintf('hAx%d',n)).YLim = [0 app.(sprintf('hImg%d',n)).YData(2)]; % manage YLim (for proper slice display)
            end

            linkaxes([app.hAx1 app.hAx2],'xy')

            set(app.UIFigure,'Name',sprintf('%d | %d',app.volCurrentSlcIdx,app.volTotalSlice))
            app.hPixInfo = impixelinfo(app.UIFigure);

            % ACTIVATE MENUS and SET LABELS
            app.VolumeMenu.Enable = "on";
            app.ColormapMenu.Enable = "on";
            app.ShowOriginalVolume.Checked = "on";

            app.SegmentMenu.Enable = ~isempty(app.MASK);
            app.PanelLabelControl.Enable = ~isempty(app.LABEL);
            app.ShowOriginalVolume.Enable = ~isempty(app.VOLf);
            
            if ~isempty(app.LABEL)
                labelRange = double([0, max(app.LABEL(:))]);
                app.LabelSpinner.Limits=labelRange;
            end

            if isempty(app.MASK)
                set(app.SegmentMenu.Children,Checked = "off")
            end
                     
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)

            if ~isempty(app.volCurrentSlcIdx)
                verticalScrollCount = event.VerticalScrollCount;

                idx = app.volCurrentSlcIdx + verticalScrollCount;
                idx = SliceIdxRollover(app,idx);
                app.volCurrentSlcIdx = idx;
                SliceUpdate(app)
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            % key = event.Key;
            switch event.Key
                case 'downarrow'
                    idx = app.volCurrentSlcIdx - 1;
                    idx = SliceIdxRollover(app,idx);
                    app.volCurrentSlcIdx = idx;
                    SliceUpdate(app)
                case 'uparrow'
                    idx = app.volCurrentSlcIdx + 1;
                    idx = SliceIdxRollover(app,idx);
                    app.volCurrentSlcIdx = idx;
                    SliceUpdate(app)
            end
        end

        % Menu selected function: OpenImageFile
        function OpenImageFileSelected(app, event)
            LoadVolume(app)
        end

        % Menu selected function: SetImagePathMenu
        function SetImagePathMenuSelected(app, event)
            app.imagePath='';
            LoadVolume(app);
        end

        % Menu selected function: OverlayChannelsMenu, ShowMaskOverlayMenu
        function OverlayMenuSelected(app, event)
            switch event.Source.Text
                case 'Overlay Channels'
                    mmSliceView(squeeze(app.VOL(:,:,1,:)), squeeze(app.VOL(:,:,2,:)))
                case 'Overlay Masks'
                    mmSliceView(squeeze(app.MASK(:,:,1,:)), squeeze(app.MASK(:,:,2,:)))
            end
        end

        % Menu selected function: FilterVolumeMenu
        function FilterVolumeMenuSelected(app, event)
            FilterVolume(app)
        end

        % Menu selected function: SegmentVolumeMenu
        function SegmentVolumeMenuSelected(app, event)
            SegmentVolume(app)
        end

        % Menu selected function: AdjustContrastMenu, 
        % ...and 4 other components
        function ToggleSelectedMenu(app, event)

            if event.Source.Checked % uncheck
                set(event.Source,'Checked','off')

                if event.Source.Text=="Show Masks"
                    app.BlendMaskswithVolsMenu.Checked = "off";
                end
            else % check
                set(event.Source.Parent.Children,'Checked','off') % turn everybody off
                set(event.Source,'Checked','on')

                % if Blend, Turn on Mask
                if event.Source.Text=="Blend Masks with Vols"
                    app.ShowMasksMenu.Checked="on";
                end
            end

            SliceUpdate(app)
        end

        % Menu selected function: SeparateLabelClusters
        function ShowSegment3D(app, event)
            if isempty(app.hVolDisp3D) || ~isvalid(app.hVolDisp3D)
                app.hVolDisp3D = volDisp3D(app); % calls helper app, which is a wrapper to volshow
            else
                app.hVolDisp3D.UpdateVolShow
                figure(app.hVolDisp3D.UIFigure)
            end
        end

        % Menu selected function: CalculateDataMenu
        function CalculateDataMenuSelected(app, event)
            if isempty(app.hDataDlg) || ~isvalid(app.hDataDlg)
                app.hDataDlg = dataDlg(app);
            else
                maxLabel = max(app.LABEL(:));
                app.hDataDlg.ClustersListBox.Items = "Cluster " + (1:maxLabel);
                figure(app.hDataDlg.UIFigure) % bring app to the front
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % if ~isempty(app.hDataDlg) && isvalid(app.hDataDlg)
            %     close(app.hDataDlg.UIFigure)
            % end

            if ~isempty(app.hVolDisp3D) && isvalid(app.hVolDisp3D)
                delete(app.hVolDisp3D);
            end

            delete(app)
        end

        % Menu selected function: RepositionPixelInfoMenu
        function RepositionPixelInfoMenuSelected(app, event)
            delete(app.hPixInfo)
            app.hPixInfo = impixelinfo(app.UIFigure);
        end

        % Menu selected function: grayMenu, hotMenu, parulaMenu, turboMenu
        function ColormapMenuSelected(app, event)
            
            if app.ShowLabelMenu.Checked && ~isempty(app.LABEL)
                maxColor = max(app.LABEL(:));
            else
                maxColor = 1;
            end

            app.hAx1.CLim = [0 maxColor];
            colormap(app.hAx1,event.Source.Text)

            if ~app.ShowLabelMenu.Checked
                colormap(app.hAx2,event.Source.Text)
            else
                colormap(app.hAx2,gray)
            end

        end

        % Menu selected function: Assign2Workspace
        function Assign2WorkspaceMenuSelected(app, event)
           
            fn={'VOL','VOLf','MASK','LABEL','volMeta'};

            for n=1:numel(fn)
                if ~isempty(app.(fn{n}))
                    assignin('base',fn{n},app.(fn{n}));
                else
                    fprintf('%s empty and not assigned \n',fn{n});
                end
            end
            
        end

        % Menu selected function: AssignImg2Workspace
        function AssignImg2WorkspaceMenuSelected(app, event)
            assignin("base","Ch1",app.hImg1.CData);
            assignin("base","Ch2",app.hImg1.CData);
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            roi = drawpoint(app.hAx1);
            im = imagemodel(app.hImg1);
            rc = round(roi.Position);
            pixval = getPixelValue(im,rc(2),rc(1));

            app.pLABEL = app.LABEL; % save backup copy
            app.LABEL(app.LABEL==pixval)=app.LabelSpinner.Value;
            SliceUpdate(app)
            delete(roi)
            
            % P=impixel(app.hAx1);
            % P
        end

        % Menu selected function: LoadfromWorkspaceMenu
        function LoadfromWorkspaceMenuSelected(app, event)
            wsVars = evalin('base','whos');
            vols2load = ["VOL","VOLf","MASK","LABEL","volMeta"];
            volsfound = string({wsVars.name});

            la = ismember(vols2load, volsfound);

            if any(la)
                idc = find(la);
                for n=1:numel(idc)
                    app.(vols2load(idc(n))) = evalin("base",vols2load(n)); % workspace match found
                end

                % if numel(idc) < 4 % clear any volumes not loaded
                %     idcx = find(~la);
                %
                %     for m=1:numel(idcs)
                %         app.(vols2load(idcx(m))) = [];
                %     end
                % end
            end

            if ~all(size(app.VOL)==size(app.VOLf))
                app.VOLf = [];
            end

            if ~all(size(app.VOL)==size(app.MASK))
                app.MASK = [];
                app.LABEL = [];
            end

            fprintf('The following were loaded in OrganoidAnalyzer:\n')
            fprintf('    - %s\n',vols2load(la))

            fprintf('Current Volume Sizes: \n')
            for n=1:4
                fprintf('- %s: ',vols2load(n))
                fprintf('%d ', size(app.(vols2load(n))))
                fprintf('\n')
            end
            SliceInit(app)
        end

        % Value changed function: MergeWith
        function MergeWithValueChanged(app, event)
            % value = app.MergeWith.Value;

            while app.MergeWith.Value
                roi = drawpoint(app.hAx1);
                im = imagemodel(app.hImg1);
                rc = round(roi.Position);
                pixval = getPixelValue(im,rc(2),rc(1));
                app.LABEL(app.LABEL==pixval)=app.LabelSpinner.Value;
                SliceUpdate(app)
                delete(roi)
            end
            
        end

        % Button pushed function: UNDOButton
        function UNDOButtonPushed(app, event)
            if ~isempty(app.pLABEL) && all(size(app.pLABEL)==size(app.LABEL))
                app.LABEL = app.pLABEL;
                SliceUpdate(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.menuFile = uimenu(app.UIFigure);
            app.menuFile.Text = 'File';
            app.OpenImageFile = uimenu(app.menuFile);
            app.OpenImageFile.MenuSelectedFcn = createCallbackFcn(app, @OpenImageFileSelected, true);
            app.OpenImageFile.Accelerator = 'o';
            app.OpenImageFile.Text = 'Open Image Volume';
            app.SetImagePathMenu = uimenu(app.menuFile);
            app.SetImagePathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetImagePathMenuSelected, true);
            app.SetImagePathMenu.Text = 'Set Image File Path';
            app.Assign2Workspace = uimenu(app.menuFile);
            app.Assign2Workspace.MenuSelectedFcn = createCallbackFcn(app, @Assign2WorkspaceMenuSelected, true);
            app.Assign2Workspace.Text = 'Assign Volumes to Workspace';
            app.AssignImg2Workspace = uimenu(app.menuFile);
            app.AssignImg2Workspace.MenuSelectedFcn = createCallbackFcn(app, @AssignImg2WorkspaceMenuSelected, true);
            app.AssignImg2Workspace.Text = 'Assign Image Slices to Workspace';
            app.LoadfromWorkspaceMenu = uimenu(app.menuFile);
            app.LoadfromWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromWorkspaceMenuSelected, true);
            app.LoadfromWorkspaceMenu.Text = 'Load Volumes from Workspace';
            app.VolumeMenu = uimenu(app.UIFigure);
            app.VolumeMenu.Enable = 'off';
            app.VolumeMenu.Text = 'Volume';
            app.AdjustContrastMenu = uimenu(app.VolumeMenu);
            app.AdjustContrastMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleSelectedMenu, true);
            app.AdjustContrastMenu.Accelerator = 'a';
            app.AdjustContrastMenu.Text = 'Adjust Contrast';
            app.OverlayChannelsMenu = uimenu(app.VolumeMenu);
            app.OverlayChannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @OverlayMenuSelected, true);
            app.OverlayChannelsMenu.Text = 'Overlay Channels';
            app.RepositionPixelInfoMenu = uimenu(app.VolumeMenu);
            app.RepositionPixelInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @RepositionPixelInfoMenuSelected, true);
            app.RepositionPixelInfoMenu.Accelerator = 'i';
            app.RepositionPixelInfoMenu.Text = 'Reposition Pixel Info';
            app.FilterVolumeMenu = uimenu(app.VolumeMenu);
            app.FilterVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterVolumeMenuSelected, true);
            app.FilterVolumeMenu.Accelerator = 'f';
            app.FilterVolumeMenu.Text = 'Filter Volume';
            app.SegmentVolumeMenu = uimenu(app.VolumeMenu);
            app.SegmentVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @SegmentVolumeMenuSelected, true);
            app.SegmentVolumeMenu.Accelerator = 'g';
            app.SegmentVolumeMenu.Text = 'Segment Volume';
            app.ShowOriginalVolume = uimenu(app.VolumeMenu);
            app.ShowOriginalVolume.MenuSelectedFcn = createCallbackFcn(app, @ToggleSelectedMenu, true);
            app.ShowOriginalVolume.Enable = 'off';
            app.ShowOriginalVolume.Accelerator = '1';
            app.ShowOriginalVolume.Checked = 'on';
            app.ShowOriginalVolume.Text = 'Show Original';
            app.SegmentMenu = uimenu(app.UIFigure);
            app.SegmentMenu.Enable = 'off';
            app.SegmentMenu.Text = 'Segment';
            app.ShowMasksMenu = uimenu(app.SegmentMenu);
            app.ShowMasksMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleSelectedMenu, true);
            app.ShowMasksMenu.Accelerator = '2';
            app.ShowMasksMenu.Text = 'Show Masks';
            app.BlendMaskswithVolsMenu = uimenu(app.SegmentMenu);
            app.BlendMaskswithVolsMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleSelectedMenu, true);
            app.BlendMaskswithVolsMenu.Text = 'Blend Masks with Vols';
            app.ShowLabelMenu = uimenu(app.SegmentMenu);
            app.ShowLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleSelectedMenu, true);
            app.ShowLabelMenu.Accelerator = '3';
            app.ShowLabelMenu.Text = 'Show Label';
            app.ShowMaskOverlayMenu = uimenu(app.SegmentMenu);
            app.ShowMaskOverlayMenu.MenuSelectedFcn = createCallbackFcn(app, @OverlayMenuSelected, true);
            app.ShowMaskOverlayMenu.Text = 'Show Mask Overlay';
            app.SeparateLabelClusters = uimenu(app.SegmentMenu);
            app.SeparateLabelClusters.MenuSelectedFcn = createCallbackFcn(app, @ShowSegment3D, true);
            app.SeparateLabelClusters.Text = 'Show Segments in 3D';
            app.CalculateDataMenu = uimenu(app.SegmentMenu);
            app.CalculateDataMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateDataMenuSelected, true);
            app.CalculateDataMenu.Text = 'Calculate Data';
            app.ColormapMenu = uimenu(app.UIFigure);
            app.ColormapMenu.Enable = 'off';
            app.ColormapMenu.Text = 'Colormap';
            app.grayMenu = uimenu(app.ColormapMenu);
            app.grayMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.grayMenu.Text = 'gray';
            app.parulaMenu = uimenu(app.ColormapMenu);
            app.parulaMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.parulaMenu.Text = 'parula';
            app.turboMenu = uimenu(app.ColormapMenu);
            app.turboMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.turboMenu.Text = 'turbo';
            app.hotMenu = uimenu(app.ColormapMenu);
            app.hotMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.hotMenu.Text = 'hot';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.hAx1 = uiaxes(app.GridLayout);
            title(app.hAx1, 'Ch1')
            app.hAx2 = uiaxes(app.GridLayout);
            title(app.hAx2, 'Ch2')
            app.Panel = uipanel(app.GridLayout);
            app.Ch2SpinnerLabel = uilabel(app.Panel);
            app.Ch2SpinnerLabel.Text = 'Ch2';
            app.Ch2Spinner = uispinner(app.Panel);
            app.Ch2Spinner.Step = 0.1;
            app.Ch2Spinner.Limits = [0 2];
            app.Ch2Spinner.Tooltip = {'Threshold factor for Ch2 (0.2 default)'};
            app.Ch2Spinner.Value = 0.2;
            app.Ch1Spinner = uispinner(app.Panel);
            app.Ch1Spinner.Step = 0.1;
            app.Ch1Spinner.Limits = [0 2];
            app.Ch1Spinner.Tooltip = {'Threshold Factor for Channel 1 (0.5 default).'};
            app.Ch1Spinner.Value = 0.5;
            app.Ch1SpinnerLabel = uilabel(app.Panel);
            app.Ch1SpinnerLabel.Text = 'Ch1';
            app.ThresholdSettingsLabel = uilabel(app.Panel);
            app.ThresholdSettingsLabel.Tooltip = {'Increasing value reduces the number of nuclei and noise captured in the indicated channel. Lowering value increases number of nuclei segmented and increases noise.'};
            app.ThresholdSettingsLabel.Text = 'Threshold Settings:';
            app.PanelLabelControl = uipanel(app.GridLayout);
            app.MergeButton = uibutton(app.PanelLabelControl, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Tooltip = {'After Button press, click on a pixel in the Ch1 Image to merge smaller cluster with selected cluster label in the label spinner'};
            app.MergeButton.Text = 'Merge';
            app.LabelSpinnerLabel = uilabel(app.PanelLabelControl);
            app.LabelSpinner = uispinner(app.PanelLabelControl);
            app.LabelSpinner.Limits = [0 1];
            app.LabelSpinner.Tooltip = {'Cluster Label. Other Clusters will be added to this cluster label after clicking the "Merge Button" and selecting a pixel in the Ch1 image. A value of 0 will set other clusters to background (and not be processed).'};
            app.LabelSpinner.Value = 1;
            app.MergeWith = uibutton(app.PanelLabelControl, 'state');
            app.MergeWith.ValueChangedFcn = createCallbackFcn(app, @MergeWithValueChanged, true);
            app.MergeWith.Visible = 'off';
            app.MergeWith.Enable = 'off';
            app.MergeWith.Text = 'Button';
            app.UNDOButton = uibutton(app.PanelLabelControl, 'push');
            app.UNDOButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOButtonPushed, true);
            app.UNDOButton.Text = 'UNDO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrganoidAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end