classdef dataDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FileMenu               matlab.ui.container.Menu
        LoadDatasetMenu        matlab.ui.container.Menu
        SaveDatasetasMenu      matlab.ui.container.Menu
        MainDatasetPanel       matlab.ui.container.Panel
        SaveButton             matlab.ui.control.Button
        DeleteButton           matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        Tables                 matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        TableCollect           matlab.ui.control.Table
        TableTemp              matlab.ui.control.Table
        ClusterPanel           matlab.ui.container.Panel
        UpdateClustersButton   matlab.ui.control.Button
        AppendButton           matlab.ui.control.Button
        CalculateSelectButton  matlab.ui.control.Button
        ClustersListBox        matlab.ui.control.ListBox
        CalculateAllButton     matlab.ui.control.Button
    end


    properties (Access = public)
        DATA % saved data
        TEMP % calculated data
    end

    properties (Access = private)
        caller % handle to OrganoidAnalyzer
        selectRow  % selected rows
        dataPathMAT % path to saved dataset
        dataPathCSV
    end

    methods (Access = private)

        function ok = GetDataPath(app)
            ok = true;
            [file,path] = uiputfile('*.mat','Select File');
            if file
                app.dataPathMAT = fullfile(path,file);
                app.dataPathCSV = fullfile(path, [extractBefore(file,'.') '.csv']);
               
            else
                uialert(app.UIFigure,"Save Canceled","Save","Icon","warning")
                ok=false;
            end
            figure(app.UIFigure)
        end

        function SaveData(app)
            DS = app.TableCollect.Data;
            save(app.dataPathMAT,'DS');
            writetable(DS,app.dataPathCSV);
            app.UIFigure.Name = 'Organoid Data Wrangler';
        end
        
        function t = GetGroupingVariables(app)
            meta = app.caller.volMeta;

            % get grouping variables
            % [~, file] = fileparts(meta.filename); % image page
            s = split(meta.fileName,"_")'; % split at underscore
            
            t = array2table(s,VariableNames = {'Plate','Treatment','Label','Mag'}); % create table
            t.Series = meta.SeriesSelected; % add series (selected volume)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;
            maxLabel = max(app.caller.LABEL(:));
            app.ClustersListBox.Items = "Cluster " + (1:maxLabel);
        end

        % Button pushed function: CalculateAllButton
        function CalculateAllButtonPushed(app, event)
            
            t = GetGroupingVariables(app);

            maxLabel = max(app.caller.LABEL(:));
            az = zeros(maxLabel,1);
            RP = table(strings(maxLabel,1), az, az, az, ...
                'VariableNames',{'Cluster', 'Ch1','Ch2','Ratio'});

            % RP = regionprops3(app.caller.LABEL,"Volume"); % get the volumes of the organoids in Ch1
            % RP.Properties.VariableNames{1} = 'Ch1';

            for n = 1:maxLabel % get the volumes of the label nuclei that are found in organoids in Ch1
                ch1Mask = squeeze(app.caller.MASK(:,:,1,:)) & app.caller.LABEL == n; % only in current cluster region
                ch2Mask = squeeze(app.caller.MASK(:,:,2,:)) & app.caller.LABEL == n; % only in current cluster region
                RP.Cluster(n) = string(n);
                RP.Ch1(n) = sum(ch1Mask,"all");
                RP.Ch2(n) = sum(ch2Mask,"all");
                RP.Ratio(n) = RP.Ch2(n)/RP.Ch1(n);
            end

            T = [repmat(t,height(RP),1) RP];
            app.TableTemp.ColumnName = T.Properties.VariableNames;
            app.TableTemp.Data = T;
            app.UIFigure.Name = 'Organoid Data Wrangler*';

        end

        % Button pushed function: AppendButton
        function AppendButtonPushed(app, event)
            app.TableCollect.ColumnName = app.TableTemp.ColumnName;
            app.TableCollect.Data = [app.TableCollect.Data; app.TableTemp.Data];
        end

        % Cell selection callback: TableCollect
        function TableCollectCellSelection(app, event)
            indices = event.Indices;
            app.selectRow = indices(:,1);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,"Delete Selected Rows?","Confirm Delete",...
                "Icon","warning");
            if selection=="OK" && ~isempty(app.selectRow)
                app.TableCollect.Data(app.selectRow,:) = [];
                app.selectRow = []; % reset selection
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.dataPathMAT) || ~exist(app.dataPathMAT,"file")
                ok = GetDataPath(app);
                if ~ok
                    return
                end
            end
            SaveData(app)
        end

        % Menu selected function: SaveDatasetasMenu
        function SaveDatasetasMenuSelected(app, event)
            ok = GetDataPath(app);
            if ~ok
                return
            end
            SaveData(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if file
                app.dataPathMAT = fullfile(path,file);
                load(app.dataPathMAT) % load DS
                app.TableCollect.Data = DS;
            end
        end

        % Button pushed function: CalculateSelectButton
        function CalculateSelectButtonPushed(app, event)
            t = GetGroupingVariables(app);

            items = string(app.ClustersListBox.Value);
            selectedClusters = str2double(extractAfter(items,"Cluster "));

            L = ismember(app.caller.LABEL, selectedClusters); 

            ch1Mask = squeeze(app.caller.MASK(:,:,1,:)) & L; % only in current cluster region
            ch2Mask = squeeze(app.caller.MASK(:,:,2,:)) & L; % only in current cluster region
           
            t.Cluster = join(string(selectedClusters)); 
            t.Ch1 = sum(ch1Mask,"all");
            t.Ch2 = sum(ch2Mask,"all");
            t.Ratio = t.Ch2/t.Ch1;

            app.TableTemp.ColumnName = t.Properties.VariableNames;
            app.TableTemp.Data = t;
            app.UIFigure.Name = 'Organoid Data Wrangler*';
            
        end

        % Button pushed function: UpdateClustersButton
        function UpdateClustersButtonPushed(app, event)
            maxLabel = double(max(app.caller.LABEL(:)));
            app.ClustersListBox.Items = "Cluster " + (1:maxLabel);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            SaveData(app)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Organoid Data Wrangler';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDatasetMenu = uimenu(app.FileMenu);
            app.LoadDatasetMenu.Text = 'Load Dataset';
            app.SaveDatasetasMenu = uimenu(app.FileMenu);
            app.SaveDatasetasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDatasetasMenuSelected, true);
            app.SaveDatasetasMenu.Text = 'Save Dataset as...';
            app.ClusterPanel = uipanel(app.UIFigure);
            app.ClusterPanel.Title = 'Cluster Controls';
            app.CalculateAllButton = uibutton(app.ClusterPanel, 'push');
            app.CalculateAllButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAllButtonPushed, true);
            app.CalculateAllButton.Text = 'Calculate All';
            app.ClustersListBox = uilistbox(app.ClusterPanel);
            app.ClustersListBox.Multiselect = 'on';
            app.ClustersListBox.Value = {'Item 1'};
            app.CalculateSelectButton = uibutton(app.ClusterPanel, 'push');
            app.CalculateSelectButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSelectButtonPushed, true);
            app.CalculateSelectButton.Text = 'Calculate Selected';
            app.AppendButton = uibutton(app.ClusterPanel, 'push');
            app.AppendButton.ButtonPushedFcn = createCallbackFcn(app, @AppendButtonPushed, true);
            app.AppendButton.Text = 'Append';
            app.UpdateClustersButton = uibutton(app.ClusterPanel, 'push');
            app.UpdateClustersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateClustersButtonPushed, true);
            app.UpdateClustersButton.Text = 'Update Clusters';
            app.Tables = uipanel(app.UIFigure);
            app.Tables.Title = 'Dataset';
            app.GridLayout = uigridlayout(app.Tables);
            app.TableTemp = uitable(app.GridLayout);
            app.TableTemp.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TableTemp.RowName = {};
            app.TableCollect = uitable(app.GridLayout);
            app.TableCollect.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TableCollect.RowName = {};
            app.TableCollect.CellSelectionCallback = createCallbackFcn(app, @TableCollectCellSelection, true);
            app.MainDatasetPanel = uipanel(app.UIFigure);
            app.MainDatasetPanel.Title = 'Main Dataset';
            app.LoadButton = uibutton(app.MainDatasetPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DeleteButton = uibutton(app.MainDatasetPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.SaveButton = uibutton(app.MainDatasetPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataDlg(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end