classdef volDisp3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Organoidsin3DUIFigure    matlab.ui.Figure
        ViewMenu                 matlab.ui.container.Menu
        LabelMenu                matlab.ui.container.Menu
        Ch1Menu                  matlab.ui.container.Menu
        Ch2Menu                  matlab.ui.container.Menu
        UpdateLabelMenu          matlab.ui.container.Menu
        ButtonPanel              matlab.ui.container.Panel
        UpdateLabelTransparency  matlab.ui.control.Slider
        UpdateAlpha              matlab.ui.control.NumericEditField
        ViewerPanel              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        caller % caller app
        hVS % volshow handle
        hVwr % viewer3D handle
    end
    
    methods (Access = private)
        
        function UpdateVolShow(app)
            delete(app.hVwr)
            app.hVwr = viewer3d(BackgroundColor="white",BackgroundGradient="off",...
                Parent=app.ViewerPanel);
            
            % get selected volume from analyzer app
            if app.caller.ShowOriginalVolume.Checked
                sVOL = app.caller.VOL;
            else
                sVOL = app.caller.VOLf;
            end

            if app.LabelMenu.Checked % display label only (for editing)
                app.hVS = volshow(app.caller.LABEL,Parent=app.hVwr);
                app.ButtonPanel.Enable = "off";
            else
                if app.Ch1Menu.Checked % visualize label and ch1
                    vol = squeeze(sVOL(:,:,1,:));
                    mask = squeeze(app.caller.MASK(:,:,1,:));
                elseif app.Ch2Menu.Checked% visualize label and ch2
                    vol = squeeze(sVOL(:,:,2,:));
                    mask = squeeze(app.caller.MASK(:,:,2,:));
                end
                app.hVS = volshow(vol,OverlayData=mask,Parent=app.hVwr);
                app.ButtonPanel.Enable = "on";

                app.hVS.Alphamap = linspace(0,app.UpdateAlpha.Value,256);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;

            UpdateVolShow(app)         

            % app.hVS = volshow(caller.LABEL, parent=app.ViewerPanel);
        end

        % Menu selected function: UpdateLabelMenu
        function UpdateLabelMenuSelected(app, event)
            if app.LabelMenu.Checked
                LBL = single(bwlabeln(app.hVS.OverlayData));
                app.hVS.OverlayData = LBL;
                app.caller.LABEL = LBL;
            else
                uialert(app.Organoidsin3DUIFigure,"Label not updated because it was not selected in the View menu", ...
                    "Label Update",Icon="warning")
            end
        end

        % Menu selected function: Ch1Menu, Ch2Menu, LabelMenu
        function ToggleSelectMenu(app, event)
            set(app.ViewMenu.Children,'Checked','off') % There can be only one
            if event.Source.Checked % uncheck
                set(event.Source,'Checked','off')               
            else % check
                set(event.Source,'Checked','on')               
            end
            UpdateVolShow(app)
        end

        % Value changed function: UpdateAlpha
        function UpdateAlphaValueChanged(app, event)
            value = app.UpdateAlpha.Value;
            app.hVS.Alphamap = linspace(0,value,256);
        end

        % Value changed function: UpdateLabelTransparency
        function UpdateLabelTransparencyValueChanged(app, event)
            value = app.UpdateLabelTransparency.Value;
            app.hVS.OverlayAlphamap = value; % 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Organoidsin3DUIFigure = uifigure('Visible', 'off');
            app.Organoidsin3DUIFigure.Name = 'Organoids in 3D';
            app.ViewMenu = uimenu(app.Organoidsin3DUIFigure);
            app.ViewMenu.Text = 'View';
            app.LabelMenu = uimenu(app.ViewMenu);
            app.LabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleSelectMenu, true);
            app.LabelMenu.Checked = 'on';
            app.LabelMenu.Text = 'Label';
            app.Ch1Menu = uimenu(app.ViewMenu);
            app.Ch1Menu.MenuSelectedFcn = createCallbackFcn(app, @ToggleSelectMenu, true);
            app.Ch1Menu.Text = 'Ch1';
            app.Ch2Menu = uimenu(app.ViewMenu);
            app.Ch2Menu.MenuSelectedFcn = createCallbackFcn(app, @ToggleSelectMenu, true);
            app.Ch2Menu.Text = 'Ch2';
            app.UpdateLabelMenu = uimenu(app.Organoidsin3DUIFigure);
            app.UpdateLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateLabelMenuSelected, true);
            app.UpdateLabelMenu.Text = 'Update Label';
            app.ViewerPanel = uipanel(app.Organoidsin3DUIFigure);
            app.ButtonPanel = uipanel(app.Organoidsin3DUIFigure);
            app.ButtonPanel.Enable = 'off';
            app.UpdateAlpha = uieditfield(app.ButtonPanel, 'numeric');
            app.UpdateAlpha.Limits = [0 1];
            app.UpdateAlpha.ValueChangedFcn = createCallbackFcn(app, @UpdateAlphaValueChanged, true);
            app.UpdateAlpha.Tag = 'volAlpha';
            app.UpdateAlpha.Tooltip = {'Set Volume Visibilty (0-1)'};
            app.UpdateAlpha.Value = 0.1;
            app.UpdateLabelTransparency = uislider(app.ButtonPanel);
            app.UpdateLabelTransparency.Limits = [0 1];
            app.UpdateLabelTransparency.ValueChangedFcn = createCallbackFcn(app, @UpdateLabelTransparencyValueChanged, true);
            app.UpdateLabelTransparency.Tooltip = {'Adjust Label Transparency'};
            app.UpdateLabelTransparency.Value = 0.25;
            % Show the figure after all components are created
            app.Organoidsin3DUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volDisp3D(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Organoidsin3DUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Organoidsin3DUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Organoidsin3DUIFigure)
        end
    end
end